classdef DSC_Experiment_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        LoadConfigFileButton          matlab.ui.control.Button
        StartExperimentButton         matlab.ui.control.Button
        ApplyExperimentParametersButton  matlab.ui.control.Button
        StopExperimentButton          matlab.ui.control.Button
        ExperimentParametersPanel     matlab.ui.container.Panel
        GridLayout7                   matlab.ui.container.GridLayout
        StartTempCEditFieldLabel      matlab.ui.control.Label
        StartTempCEditField           matlab.ui.control.NumericEditField
        EndTempCEditFieldLabel        matlab.ui.control.Label
        EndTempCEditField             matlab.ui.control.NumericEditField
        RateCminEditFieldLabel        matlab.ui.control.Label
        RateCminEditField             matlab.ui.control.NumericEditField
        HoldTimesecEditFieldLabel     matlab.ui.control.Label
        HoldTimesecEditField          matlab.ui.control.NumericEditField
        SetSerialPortButton           matlab.ui.control.Button
        SerialPortEditFieldLabel      matlab.ui.control.Label
        SerialPortEditField           matlab.ui.control.EditField
        CenterPanel                   matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        UIAxes                        matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        ElapsedTimesecEditFieldLabel  matlab.ui.control.Label
        ElapsedTimesecEditField       matlab.ui.control.NumericEditField
        TargetTempCEditFieldLabel     matlab.ui.control.Label
        TargetTempCEditField          matlab.ui.control.NumericEditField
        ReferenceSampleLiveDataPanel  matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        TemperatureCEditFieldLabel    matlab.ui.control.Label
        TemperatureCEditField         matlab.ui.control.NumericEditField
        CurrentmAEditFieldLabel       matlab.ui.control.Label
        CurrentmAEditField            matlab.ui.control.NumericEditField
        PWMDutyCycleEditFieldLabel    matlab.ui.control.Label
        PWMDutyCycleEditField         matlab.ui.control.NumericEditField
        TestSampleLiveDataPanel       matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        TemperatureCEditField_2Label  matlab.ui.control.Label
        TemperatureCEditField_2       matlab.ui.control.NumericEditField
        CurrentmAEditField_2Label     matlab.ui.control.Label
        CurrentmAEditField_2          matlab.ui.control.NumericEditField
        PWMDutyCycleEditField_2Label  matlab.ui.control.Label
        PWMDutyCycleEditField_2       matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    %   DSC_v2: UI and control systems for prototype DSC system
    %       Copyright (C) 2020  Christian Kunis
    %
    %       This program is free software: you can redistribute it and/or modify
    %       it under the terms of the GNU General Public License as published by
    %       the Free Software Foundation, either version 3 of the License, or
    %       (at your option) any later version.
    %
    %       This program is distributed in the hope that it will be useful,
    %       but WITHOUT ANY WARRANTY; without even the implied warranty of
    %       MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
    %       GNU General Public License for more details.
    %
    %       You should have received a copy of the GNU General Public License
    %       along with this program. If not, see <https://www.gnu.org/licenses/>.
    %
    %       You may contact the author at ckunis.contact@gmail.com
    
    properties (Access = private)
        Arduino % The serialport object used for communications
        SerialPort % The name of the serial port to be used
        SerialPortList % The list of available serial ports
        
        TargetLine % Animate line object for the target temperature
        RefSampleLine % Animated line object for the reference sample
        TestSampleLine % Animated line object for the test sample
        
        % The number of new samples to wait before attempting to update
        % the numeric fields
        DataRefreshDelay = 1;
        
        % The number of new samples to wait before attempting to refresh
        % the plots
        PlotRefreshDelay = 100;
        
        Kp
        Ki
        Kd
    end
    
    methods (Access = private)
        
        function configLoadStatus = loadConfigFile(app)
            %   Load control parameters from a .ini file
            
            ini = IniConfig();
            
            % Prompt the user to select a file
            [configFileName, configFilePath] = uigetfile('*.ini');
            
            switch configFileName
                case 0
                    % Cancel the read operation and return an empty array
                    % if the user closes the file selection window
                    configLoadStatus = false;
                    return
                    
                otherwise
                    % Create fully-formed filename as a string
                    configFullPath = fullfile(configFilePath, configFileName);
                    
                    % Read the .ini file
                    ini.ReadFile(configFullPath)
                    
                    PIDSection = 'PID Settings';
                    if ini.IsSections(PIDSection)
                        if ini.IsKeys(PIDSection,'Kp')
                            app.Kp = ...
                                ini.GetValues('PID Constants','Kp');
                        end
                        
                        if ini.IsKeys(PIDSection,'Ki')
                            app.Ki = ...
                                ini.GetValues('PID Constants','Ki');
                        end
                        
                        if ini.IsKeys(PIDSection,'Kd')
                            app.Kd = ...
                                ini.GetValues('PID Constants','Kd');
                        end
                        
                    else
                        warningMessage = sprintf("The seleced .ini file does not contain a [%s] section", PIDSection);
                        warndlg(warningMessage)
                        warning(warningMessage)
                        configLoadStatus = false;
                        return
                    end
                    
                    TempControlSection = 'Temperature Control';
                    if ini.IsSections(TempControlSection)
                        if ini.IsKeys(TempControlSection,'startTemp')
                            app.StartTempCEditField.Value = ...
                                ini.GetValues(TempControlSection,'startTemp');
                        end
                        
                        if ini.IsKeys(TempControlSection,'endTemp')
                            app.EndTempCEditField.Value = ...
                                ini.GetValues(TempControlSection,'endTemp');
                        end
                        
                        if ini.IsKeys(TempControlSection,'rampUpRate')
                            app.RateCminEditField.Value = ...
                                ini.GetValues(TempControlSection,'rampUpRate');
                        end
                        
                        if ini.IsKeys(TempControlSection,'holdTime')
                            app.HoldTimesecEditField.Value = ...
                                ini.GetValues(TempControlSection,'holdTime');
                        end
                        
                    else
                        warningMessage = sprintf("The seleced .ini file does not contain a [%s] section", TempControlSection);
                        warndlg(warningMessage)
                        warning(warningMessage)
                        configLoadStatus = false;
                        return
                    end
            end
        end
        
        function sendPIDGains(app)
            % Send the PID gain constants via the serial bus
            write(app.Arduino, 'p', 'char');
            
            write(app.Arduino, string(app.Kp), 'string');
            write(app.Arduino, ' ', 'char');
            write(app.Arduino, string(app.Ki), 'string');
            write(app.Arduino, ' ', 'char');
            write(app.Arduino, string(app.Kd), 'string');
        end
        
        function receivePIDGains(app)
            % Receive the PID gain constants via the serial bus
            serialData = read(app.Arduino, 1, 'char');
            switch serialData
                case 'k'
                    readline(app.Arduino);
                    app.Kp = double(readline(app.Arduino));
                    app.Ki = double(readline(app.Arduino));
                    app.Kd = double(readline(app.Arduino));
                otherwise
                    disp('Unrecognized control param flag:')
                    disp(serialData)
                    disp(readline(app.Arduino))
            end
        end
        
        function sendControlParameters(app)
            write(app.Arduino, 'l', 'char');
            
            write(app.Arduino, string(app.StartTempCEditField.Value), 'string');
            write(app.Arduino, ' ', 'char');
            write(app.Arduino, string(app.EndTempCEditField.Value), 'string');
            write(app.Arduino, ' ', 'char');
            write(app.Arduino, string(app.RateCminEditField.Value), 'string');
            write(app.Arduino, ' ', 'char');
            write(app.Arduino, string(app.HoldTimesecEditField.Value), 'string');
        end
        
        function receiveControlParameters(app)
            serialData = read(app.Arduino, 1, 'char');
            switch serialData
                case 'c'
                    readline(app.Arduino);
                    app.StartTempCEditField.Value = double(readline(app.Arduino));
                    app.EndTempCEditField.Value = double(readline(app.Arduino));
                    app.RateCminEditField.Value = double(readline(app.Arduino));
                    app.HoldTimesecEditField.Value = double(readline(app.Arduino));
                otherwise
                    disp('Unrecognized control param flag:')
                    disp(serialData)
                    disp(readline(app.Arduino))
            end
        end
        
        function receiveSerialData(app)
            startDateTime = datetime;
            mkdir('autosave');
            matfileName = ['autosave/autoSaveData-',datestr(startDateTime, 'yyyy-mm-dd-HHMM'),'.mat'];
            
            elapsedTime = zeros(1,app.PlotRefreshDelay);
            targetTemp = zeros(1,app.PlotRefreshDelay);
            refTemp = zeros(1,app.PlotRefreshDelay);
            sampTemp = zeros(1,app.PlotRefreshDelay);
            refCurrent = zeros(1,app.PlotRefreshDelay);
            sampCurrent = zeros(1,app.PlotRefreshDelay);
            refHeatFlow = zeros(1,app.PlotRefreshDelay);
            sampHeatFlow = zeros(1,app.PlotRefreshDelay);
            refDutyCycle = zeros(1,app.PlotRefreshDelay);
            sampDutyCycle = zeros(1,app.PlotRefreshDelay);
            
            dataLength = 0;
            
            experimentIsRunning = true;
            while experimentIsRunning
                serialData = read(app.Arduino, 1, 'char');
                
                switch serialData
                    case 'd'
                        readline(app.Arduino);
                        dataLength = dataLength + 1;
                        elapsedTime(dataLength) = double(readline(app.Arduino));
                        targetTemp(dataLength) = double(readline(app.Arduino));
                        refTemp(dataLength) = double(readline(app.Arduino));
                        sampTemp(dataLength) = double(readline(app.Arduino));
                        refCurrent(dataLength) = double(readline(app.Arduino));
                        sampCurrent(dataLength) = double(readline(app.Arduino));
                        refHeatFlow(dataLength) = double(readline(app.Arduino));
                        sampHeatFlow(dataLength) = double(readline(app.Arduino));
                        refDutyCycle(dataLength) = double(readline(app.Arduino));
                        sampDutyCycle(dataLength) = double(readline(app.Arduino));
                        
                        if ~mod(dataLength, app.DataRefreshDelay)
                            updateLiveData(app, ...
                                elapsedTime(dataLength), ...
                                targetTemp(dataLength), ...
                                refTemp(dataLength), ...
                                sampTemp(dataLength), ...
                                refCurrent(dataLength), ...
                                sampCurrent(dataLength), ...
                                refDutyCycle(dataLength), ...
                                sampDutyCycle(dataLength));
                        end
                        
                        if ~mod(dataLength, app.PlotRefreshDelay)
                            refreshLivePlot(app, elapsedTime,...
                                targetTemp, refTemp, sampTemp);
                        end
                    case 'x'
                        experimentIsRunning = false;
                        disp('Received end signal')
                    otherwise
                        disp('Unrecognized data flag:')
                        disp(serialData)
                        disp(readline(app.Arduino))
                end
            end
            
            save(matfileName, 'startDateTime', 'elapsedTime', 'targetTemp', ...
                'refTemp', 'sampTemp', ...
                'refCurrent', 'sampCurrent', ...
                'refHeatFlow', 'sampHeatFlow', ...
                'refDutyCycle', 'sampDutyCycle', 'dataLength')
            
            updateLiveData(app, ...
                elapsedTime(dataLength), ...
                targetTemp(dataLength), ...
                refTemp(dataLength), ...
                sampTemp(dataLength), ...
                refCurrent(dataLength), ...
                sampCurrent(dataLength), ...
                refDutyCycle(dataLength), ...
                sampDutyCycle(dataLength));
            
            refreshLivePlot(app, elapsedTime, targetTemp, refTemp, sampTemp);
            
            setIdleUI(app);
        end
        
        function setRunningUI(app)
            app.StartExperimentButton.Enable = 'off';
            app.StopExperimentButton.Enable = 'on';
            app.LoadConfigFileButton.Enable = 'off';
            app.ApplyExperimentParametersButton.Enable = 'off';
            app.StartTempCEditField.Editable = 'off';
            app.EndTempCEditField.Editable = 'off';
            app.RateCminEditField.Editable = 'off';
            app.HoldTimesecEditField.Editable = 'off';
            app.SetSerialPortButton.Enable = 'off';
            app.SerialPortEditField.Editable = 'off';
        end
        
        function setIdleUI(app)
            app.StartExperimentButton.Enable = 'on';
            app.StopExperimentButton.Enable = 'off';
            app.LoadConfigFileButton.Enable = 'on';
            app.ApplyExperimentParametersButton.Enable = 'on';
            app.StartTempCEditField.Editable = 'on';
            app.EndTempCEditField.Editable = 'on';
            app.RateCminEditField.Editable = 'on';
            app.HoldTimesecEditField.Editable = 'on';
            app.SetSerialPortButton.Enable = 'on';
            app.SerialPortEditField.Editable = 'on';
        end
        
        function updateLiveData(app, elapsedTime, targetTemp, ...
                refTemp, sampTemp, refCurrent, sampCurrent, ...
                refDutyCycle, sampDutyCycle)
            drawnow limitrate nocallbacks
            
            % Convert from milliseconds to seconds
            app.ElapsedTimesecEditField.Value = elapsedTime / 1000;
            
            app.TargetTempCEditField.Value = targetTemp;
            
            app.TemperatureCEditField.Value = refTemp;
            app.CurrentmAEditField.Value = refCurrent;
            app.PWMDutyCycleEditField.Value = refDutyCycle;
            
            app.TemperatureCEditField_2.Value = sampTemp;
            app.CurrentmAEditField_2.Value = sampCurrent;
            app.PWMDutyCycleEditField_2.Value = sampDutyCycle;
            
            drawnow limitrate
        end
        
        function refreshLivePlot(app, elapsedTimeArray,...
                targetTempArray, refTempArray, sampTempArray)
            
            % Convert from milliseconds to seconds
            timeInSeconds = elapsedTimeArray ./ 1000;
            
            drawnow limitrate nocallbacks
            
            clearpoints(app.TargetLine)
            clearpoints(app.RefSampleLine)
            clearpoints(app.TestSampleLine)
            
            % Update the plots
            addpoints(app.TargetLine, timeInSeconds, targetTempArray)
            addpoints(app.RefSampleLine, timeInSeconds, refTempArray)
            addpoints(app.TestSampleLine, timeInSeconds, sampTempArray)
            
            legend(app.UIAxes, 'Location', 'best')
            
            drawnow limitrate
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Get the list of available serial ports
            app.SerialPortList = serialportlist("available");
            
            if (isempty(app.SerialPortList))
                % Display a warning if no serial ports found
                warndlg('No available serial ports were found. Make sure the arduino device is plugged into this computer via USB')
            else
                % Set the default serial port to the last port in the list
                app.SerialPort = app.SerialPortList(end);
                
                % Update the serial port edit field
                app.SerialPortEditField.Value = app.SerialPort;
                
                % Create an serial port object where you specify the USB port
                % (look in Arduino->tools -> port and the baud rate (9600)
                app.Arduino = serialport(app.SerialPort, 9600);
                
                % Request the temperature control parameters from the arduino
                write(app.Arduino, 'i', 'char');
                receivePIDGains(app);
                receiveControlParameters(app);
            end
            
            % Create the animatedline objects
            app.TargetLine = animatedline(app.UIAxes, 'Color', 'black', ...
                'LineStyle', ':');
            app.RefSampleLine = animatedline(app.UIAxes, 'Color', 'blue', ...
                'LineStyle', '--');
            app.TestSampleLine = animatedline(app.UIAxes, 'Color', 'red', ...
                'LineStyle', '-.');
            
            % Create a legend for the temperature plot
            legend(app.UIAxes, 'Target Temperature', 'Reference Sample', ...
                'Test Sample', 'Location', 'best')
            
            setIdleUI(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: StartExperimentButton
        function StartExperimentButtonPushed(app, event)
            write(app.Arduino, 's', 'char');
            
            serialData = read(app.Arduino, 1, 'char');
            switch serialData
                case 's'
                    readline(app.Arduino);
                    setRunningUI(app);
                    receiveSerialData(app);
            end
        end

        % Button pushed function: StopExperimentButton
        function StopExperimentButtonPushed(app, event)
            write(app.Arduino, 'x', 'char');
        end

        % Button pushed function: LoadConfigFileButton
        function LoadConfigFileButtonPushed(app, event)
            loadConfigFile(app);
            
            sendPIDGains(app);
            receivePIDGains(app);
            
            sendControlParameters(app);
            receiveControlParameters(app);
        end

        % Button pushed function: ApplyExperimentParametersButton
        function ApplyExperimentParametersButtonPushed(app, event)
            sendPIDGains(app);
            receivePIDGains(app);
            
            sendControlParameters(app);
            receiveControlParameters(app);
        end

        % Button pushed function: SetSerialPortButton
        function SetSerialPortButtonPushed(app, event)
            delete(app.Arduino);
            
            % Get the list of available serial ports
            app.SerialPortList = serialportlist("available");
            
            if any(contains(app.SerialPortList, app.SerialPortEditField.Value))
                app.SerialPort = app.SerialPortEditField.Value;
                
                % Create an serial port object where you specify the USB port
                % (look in Arduino->tools -> port and the baud rate (9600)
                app.Arduino = serialport(app.SerialPort, 9600);
            else
                warndlg(sprintf("%s is not in the list of available serial ports", app.SerialPortEditField.Value));
                disp(app.SerialPortList)
                disp(app.SerialPortEditField.Value)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.LoadConfigFileButton = uibutton(app.GridLayout2, 'push');
            app.LoadConfigFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigFileButtonPushed, true);
            app.LoadConfigFileButton.Text = 'Load Config File';
            app.StartExperimentButton = uibutton(app.GridLayout2, 'push');
            app.StartExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartExperimentButtonPushed, true);
            app.StartExperimentButton.Text = 'Start Experiment';
            app.ApplyExperimentParametersButton = uibutton(app.GridLayout2, 'push');
            app.ApplyExperimentParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyExperimentParametersButtonPushed, true);
            app.ApplyExperimentParametersButton.Text = 'Apply Experiment Parameters';
            app.StopExperimentButton = uibutton(app.GridLayout2, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperimentButtonPushed, true);
            app.StopExperimentButton.Text = 'Stop Experiment';
            app.ExperimentParametersPanel = uipanel(app.GridLayout2);
            app.ExperimentParametersPanel.Title = 'Experiment Parameters';
            app.GridLayout7 = uigridlayout(app.ExperimentParametersPanel);
            app.StartTempCEditFieldLabel = uilabel(app.GridLayout7);
            app.StartTempCEditFieldLabel.Text = 'Start Temp (C)';
            app.StartTempCEditField = uieditfield(app.GridLayout7, 'numeric');
            app.StartTempCEditField.Limits = [-300 300];
            app.EndTempCEditFieldLabel = uilabel(app.GridLayout7);
            app.EndTempCEditFieldLabel.Text = 'End Temp (C)';
            app.EndTempCEditField = uieditfield(app.GridLayout7, 'numeric');
            app.EndTempCEditField.Limits = [-300 300];
            app.RateCminEditFieldLabel = uilabel(app.GridLayout7);
            app.RateCminEditFieldLabel.Text = 'Rate (C/min)';
            app.RateCminEditField = uieditfield(app.GridLayout7, 'numeric');
            app.RateCminEditField.Limits = [0 Inf];
            app.HoldTimesecEditFieldLabel = uilabel(app.GridLayout7);
            app.HoldTimesecEditFieldLabel.Text = 'Hold Time (sec)';
            app.HoldTimesecEditField = uieditfield(app.GridLayout7, 'numeric');
            app.HoldTimesecEditField.Limits = [0 Inf];
            app.SetSerialPortButton = uibutton(app.GridLayout2, 'push');
            app.SetSerialPortButton.ButtonPushedFcn = createCallbackFcn(app, @SetSerialPortButtonPushed, true);
            app.SetSerialPortButton.Text = 'Set Serial Port';
            app.SerialPortEditFieldLabel = uilabel(app.GridLayout2);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.SerialPortEditField = uieditfield(app.GridLayout2, 'text');
            app.SerialPortEditField.Value = 'COM1';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Temperature vs. Time')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'Temperature (C)')
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.ElapsedTimesecEditFieldLabel = uilabel(app.GridLayout4);
            app.ElapsedTimesecEditFieldLabel.Text = 'Elapsed Time (sec)';
            app.ElapsedTimesecEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ElapsedTimesecEditField.ValueDisplayFormat = '%.3f';
            app.ElapsedTimesecEditField.Editable = 'off';
            app.TargetTempCEditFieldLabel = uilabel(app.GridLayout4);
            app.TargetTempCEditFieldLabel.Text = 'Target Temp (C)';
            app.TargetTempCEditField = uieditfield(app.GridLayout4, 'numeric');
            app.TargetTempCEditField.ValueDisplayFormat = '%.3f';
            app.TargetTempCEditField.Editable = 'off';
            app.ReferenceSampleLiveDataPanel = uipanel(app.GridLayout4);
            app.ReferenceSampleLiveDataPanel.Title = 'Reference Sample Live Data';
            app.GridLayout5 = uigridlayout(app.ReferenceSampleLiveDataPanel);
            app.TemperatureCEditFieldLabel = uilabel(app.GridLayout5);
            app.TemperatureCEditFieldLabel.Text = 'Temperature (C)';
            app.TemperatureCEditField = uieditfield(app.GridLayout5, 'numeric');
            app.TemperatureCEditField.ValueDisplayFormat = '%.3f';
            app.TemperatureCEditField.Editable = 'off';
            app.CurrentmAEditFieldLabel = uilabel(app.GridLayout5);
            app.CurrentmAEditFieldLabel.Text = 'Current (mA)';
            app.CurrentmAEditField = uieditfield(app.GridLayout5, 'numeric');
            app.CurrentmAEditField.ValueDisplayFormat = '%.2f';
            app.CurrentmAEditField.Editable = 'off';
            app.PWMDutyCycleEditFieldLabel = uilabel(app.GridLayout5);
            app.PWMDutyCycleEditFieldLabel.Text = 'PWM Duty Cycle';
            app.PWMDutyCycleEditField = uieditfield(app.GridLayout5, 'numeric');
            app.PWMDutyCycleEditField.ValueDisplayFormat = '%.2f';
            app.PWMDutyCycleEditField.Editable = 'off';
            app.TestSampleLiveDataPanel = uipanel(app.GridLayout4);
            app.TestSampleLiveDataPanel.Title = 'Test Sample Live Data';
            app.GridLayout6 = uigridlayout(app.TestSampleLiveDataPanel);
            app.TemperatureCEditField_2Label = uilabel(app.GridLayout6);
            app.TemperatureCEditField_2Label.Text = 'Temperature (C)';
            app.TemperatureCEditField_2 = uieditfield(app.GridLayout6, 'numeric');
            app.TemperatureCEditField_2.ValueDisplayFormat = '%.3f';
            app.TemperatureCEditField_2.Editable = 'off';
            app.CurrentmAEditField_2Label = uilabel(app.GridLayout6);
            app.CurrentmAEditField_2Label.Text = 'Current (mA)';
            app.CurrentmAEditField_2 = uieditfield(app.GridLayout6, 'numeric');
            app.CurrentmAEditField_2.ValueDisplayFormat = '%.2f';
            app.CurrentmAEditField_2.Editable = 'off';
            app.PWMDutyCycleEditField_2Label = uilabel(app.GridLayout6);
            app.PWMDutyCycleEditField_2Label.Text = 'PWM Duty Cycle';
            app.PWMDutyCycleEditField_2 = uieditfield(app.GridLayout6, 'numeric');
            app.PWMDutyCycleEditField_2.ValueDisplayFormat = '%.2f';
            app.PWMDutyCycleEditField_2.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSC_Experiment_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end